classdef my_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Menu                   matlab.ui.container.Menu
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        aEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        bEditFieldLabel        matlab.ui.control.Label
        bEditField             matlab.ui.control.NumericEditField
        SLabel                 matlab.ui.control.Label
        SEditField             matlab.ui.control.NumericEditField
        RunButton              matlab.ui.control.Button
        Label_3                matlab.ui.control.Label
        REditFieldLabel        matlab.ui.control.Label
        REditField             matlab.ui.control.NumericEditField
        SEditField_2Label      matlab.ui.control.Label
        SEditField_2           matlab.ui.control.NumericEditField
        RunButton_2            matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        bEditField_2Label      matlab.ui.control.Label
        bEditField_2           matlab.ui.control.NumericEditField
        aEditField_2Label      matlab.ui.control.Label
        aEditField_2           matlab.ui.control.NumericEditField
        hEditFieldLabel        matlab.ui.control.Label
        hEditField             matlab.ui.control.NumericEditField
        RunButton_3            matlab.ui.control.StateButton
        SEditField_3Label      matlab.ui.control.Label
        SEditField_3           matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        aEditField_3Label      matlab.ui.control.Label
        aEditField_3           matlab.ui.control.NumericEditField
        hEditField_2Label      matlab.ui.control.Label
        hEditField_2           matlab.ui.control.NumericEditField
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        SEditField_4Label      matlab.ui.control.Label
        SEditField_4           matlab.ui.control.NumericEditField
        aEditField_4Label      matlab.ui.control.Label
        aEditField_4           matlab.ui.control.NumericEditField
        bEditField_3Label      matlab.ui.control.Label
        bEditField_3           matlab.ui.control.NumericEditField
        alphaEditFieldLabel    matlab.ui.control.Label
        alphaEditField         matlab.ui.control.NumericEditField
        RunButton_5            matlab.ui.control.StateButton
        SEditField_5Label      matlab.ui.control.Label
        SEditField_5           matlab.ui.control.NumericEditField
        Image4                 matlab.ui.control.Image
        RunButton_4            matlab.ui.control.StateButton
        Label_6                matlab.ui.control.Label
        RunButton_6            matlab.ui.control.StateButton
        aEditField_5Label      matlab.ui.control.Label
        aEditField_5           matlab.ui.control.NumericEditField
        hEditField_3Label      matlab.ui.control.Label
        hEditField_3           matlab.ui.control.NumericEditField
        SEditField_6Label      matlab.ui.control.Label
        SEditField_6           matlab.ui.control.NumericEditField
        RunButton_7            matlab.ui.control.StateButton
        aEditField_6Label      matlab.ui.control.Label
        aEditField_6           matlab.ui.control.NumericEditField
        bEditField_4Label      matlab.ui.control.Label
        bEditField_4           matlab.ui.control.NumericEditField
        alphaEditField_2Label  matlab.ui.control.Label
        alphaEditField_2       matlab.ui.control.NumericEditField
        SEditField_7Label      matlab.ui.control.Label
        SEditField_7           matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        RunButton_8            matlab.ui.control.StateButton
        aEditField_7Label      matlab.ui.control.Label
        aEditField_7           matlab.ui.control.NumericEditField
        hEditField_4Label      matlab.ui.control.Label
        hEditField_4           matlab.ui.control.NumericEditField
        SEditField_8Label      matlab.ui.control.Label
        SEditField_8           matlab.ui.control.NumericEditField
        RunButton_9            matlab.ui.control.StateButton
        aEditField_8Label      matlab.ui.control.Label
        aEditField_8           matlab.ui.control.NumericEditField
        alphaEditField_3Label  matlab.ui.control.Label
        alphaEditField_3       matlab.ui.control.NumericEditField
        SEditField_9Label      matlab.ui.control.Label
        SEditField_9           matlab.ui.control.NumericEditField
        Image6                 matlab.ui.control.Image
        Image7                 matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath приложение;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.SEditField.Value = app.aEditField.Value*app.bEditField.Value;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            app.SEditField_2.Value = pi*(app.REditField.Value)^2;
        end

        % Value changed function: RunButton_3
        function RunButton_3ValueChanged(app, event)
           a = app.aEditField_2.Value;
           b = app.bEditField_2.Value;
           h = app.hEditField.Value;
           app.SEditField_3.Value = ((a+b)/2)*h;
        end

        % Value changed function: RunButton_5
        function RunButton_5ValueChanged(app, event)
            a = app.aEditField_4.Value;
            b = app.bEditField_3.Value;
            alpha = app.alphaEditField.Value;
            app.SEditField_5.Value = 0.5*a*b*abs(sin(alpha));
        end

        % Value changed function: RunButton_4
        function RunButton_4ValueChanged2(app, event)
            a = app.aEditField_3.Value;
            h = app.hEditField_2.Value;
            app.SEditField_4.Value = 0.5*a*h;
        end

        % Value changed function: RunButton_6
        function RunButton_6ValueChanged(app, event)
            a = app.aEditField_5.Value;
            h = app.hEditField_3.Value;
            app.SEditField_6.Value = 0.5*a*h;
        end

        % Value changed function: RunButton_7
        function RunButton_7ValueChanged(app, event)
             a = app.aEditField_6.Value;
            b = app.bEditField_4.Value;
            alpha = app.alphaEditField_2.Value;
            app.SEditField_7.Value = 0.5*a*b*abs(sin(alpha));
        end

        % Value changed function: RunButton_8
        function RunButton_8ValueChanged(app, event)
            a = app.aEditField_7.Value;
            h = app.hEditField_4.Value;
            app.SEditField_8.Value = a*h;
        end

        % Value changed function: RunButton_9
        function RunButton_9ValueChanged(app, event)
           a = app.aEditField_8.Value;
           alpha = app.alphaEditField_3.Value;
           app.SEditField_9.Value = a^2*abs(sin(alpha));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Вычисление площадей фигур';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Прямоугольник';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b:';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S = ';
            app.SEditField = uieditfield(app.UIFigure, 'numeric');
            app.SEditField.Editable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'Круг'; ''};
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R:';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_2Label = uilabel(app.UIFigure);
            app.SEditField_2Label.Text = 'S = ';
            app.SEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RunButton_2 = uibutton(app.UIFigure, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = {'Run'; ''};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Трапеция';
            app.bEditField_2Label = uilabel(app.UIFigure);
            app.bEditField_2Label.Text = 'b:';
            app.bEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.aEditField_2Label = uilabel(app.UIFigure);
            app.aEditField_2Label.Text = 'a:';
            app.aEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h:';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton_3 = uibutton(app.UIFigure, 'state');
            app.RunButton_3.ValueChangedFcn = createCallbackFcn(app, @RunButton_3ValueChanged, true);
            app.RunButton_3.Text = 'Run';
            app.SEditField_3Label = uilabel(app.UIFigure);
            app.SEditField_3Label.Text = 'S:';
            app.SEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Треугольник';
            app.aEditField_3Label = uilabel(app.UIFigure);
            app.aEditField_3Label.Text = 'a:';
            app.aEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.hEditField_2Label = uilabel(app.UIFigure);
            app.hEditField_2Label.Text = 'h:';
            app.hEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pu_2.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'circ.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'trap.jpg';
            app.SEditField_4Label = uilabel(app.UIFigure);
            app.SEditField_4Label.Text = 'S = ';
            app.SEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.aEditField_4Label = uilabel(app.UIFigure);
            app.aEditField_4Label.Text = 'a:';
            app.aEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.bEditField_3Label = uilabel(app.UIFigure);
            app.bEditField_3Label.Text = 'b:';
            app.bEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha:';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton_5 = uibutton(app.UIFigure, 'state');
            app.RunButton_5.ValueChangedFcn = createCallbackFcn(app, @RunButton_5ValueChanged, true);
            app.RunButton_5.Text = 'Run';
            app.SEditField_5Label = uilabel(app.UIFigure);
            app.SEditField_5Label.Text = 'S = ';
            app.SEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'treo.jpg';
            app.RunButton_4 = uibutton(app.UIFigure, 'state');
            app.RunButton_4.ValueChangedFcn = createCallbackFcn(app, @RunButton_4ValueChanged2, true);
            app.RunButton_4.Text = 'Run';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Параллелограмм';
            app.RunButton_6 = uibutton(app.UIFigure, 'state');
            app.RunButton_6.ValueChangedFcn = createCallbackFcn(app, @RunButton_6ValueChanged, true);
            app.RunButton_6.Text = 'Run';
            app.aEditField_5Label = uilabel(app.UIFigure);
            app.aEditField_5Label.Text = 'a:';
            app.aEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.hEditField_3Label = uilabel(app.UIFigure);
            app.hEditField_3Label.Text = 'h:';
            app.hEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_6Label = uilabel(app.UIFigure);
            app.SEditField_6Label.Text = 'S = ';
            app.SEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.RunButton_7 = uibutton(app.UIFigure, 'state');
            app.RunButton_7.ValueChangedFcn = createCallbackFcn(app, @RunButton_7ValueChanged, true);
            app.RunButton_7.Text = 'Run';
            app.aEditField_6Label = uilabel(app.UIFigure);
            app.aEditField_6Label.Text = 'a:';
            app.aEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.bEditField_4Label = uilabel(app.UIFigure);
            app.bEditField_4Label.Text = 'b:';
            app.bEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField_2Label = uilabel(app.UIFigure);
            app.alphaEditField_2Label.Text = 'alpha:';
            app.alphaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_7Label = uilabel(app.UIFigure);
            app.SEditField_7Label.Text = 'S = ';
            app.SEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Ромб';
            app.RunButton_8 = uibutton(app.UIFigure, 'state');
            app.RunButton_8.ValueChangedFcn = createCallbackFcn(app, @RunButton_8ValueChanged, true);
            app.RunButton_8.Text = 'Run';
            app.aEditField_7Label = uilabel(app.UIFigure);
            app.aEditField_7Label.Text = 'a:';
            app.aEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.hEditField_4Label = uilabel(app.UIFigure);
            app.hEditField_4Label.Text = 'h:';
            app.hEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_8Label = uilabel(app.UIFigure);
            app.SEditField_8Label.Text = 'S = ';
            app.SEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.RunButton_9 = uibutton(app.UIFigure, 'state');
            app.RunButton_9.ValueChangedFcn = createCallbackFcn(app, @RunButton_9ValueChanged, true);
            app.RunButton_9.Text = 'Run';
            app.aEditField_8Label = uilabel(app.UIFigure);
            app.aEditField_8Label.Text = 'a:';
            app.aEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField_3Label = uilabel(app.UIFigure);
            app.alphaEditField_3Label.Text = 'alpha:';
            app.alphaEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_9Label = uilabel(app.UIFigure);
            app.SEditField_9Label.Text = 'S = ';
            app.SEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'ronb.jpg';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'parall.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end